classdef THz_MIMO_ISAC_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image2                          matlab.ui.control.Image
        SensingAidedTerahertzMIMOISACSystemSimulatorLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        ParametersInitializationTab     matlab.ui.container.Tab
        ISACSystemParametersPanel       matlab.ui.container.Panel
        ArrayModelDropDown              matlab.ui.control.DropDown
        ArrayModelDropDownLabel         matlab.ui.control.Label
        DAbsoluteVelocitymsEditField_2  matlab.ui.control.EditField
        DAbsoluteVelocitymsEditField_2Label  matlab.ui.control.Label
        NumberofRxAntennasDropDown      matlab.ui.control.DropDown
        NumberofRxAntennasDropDownLabel  matlab.ui.control.Label
        TxPowerperTxAntennadBmEditField  matlab.ui.control.NumericEditField
        TxpowerdBmLabel                 matlab.ui.control.Label
        RxNoiseFiguredBEditField        matlab.ui.control.NumericEditField
        RxNoiseFiguredBEditFieldLabel   matlab.ui.control.Label
        RxGainperRxAntennadBEditField   matlab.ui.control.NumericEditField
        RxGainperRxAntennadBEditFieldLabel  matlab.ui.control.Label
        NumberofTxAntennasDropDown      matlab.ui.control.DropDown
        NumberofTxAntennasDropDownLabel  matlab.ui.control.Label
        TxGainperTxAntennadBEditField   matlab.ui.control.NumericEditField
        TxGainperTxAntennadBEditFieldLabel  matlab.ui.control.Label
        UEParametersPanel               matlab.ui.container.Panel
        NumberofRxAntennasDropDown_2    matlab.ui.control.DropDown
        NumberofRxAntennasDropDown_2Label  matlab.ui.control.Label
        RxGainperRxAntennadBEditField_2  matlab.ui.control.NumericEditField
        RxGainperRxAntennadBEditField_2Label  matlab.ui.control.Label
        DAbsoluteVelocitymsEditField    matlab.ui.control.EditField
        AbsoluteVelocitykmhLabel        matlab.ui.control.Label
        AzimuthAngleEditField           matlab.ui.control.EditField
        AzimuthandElevationAngleLabel   matlab.ui.control.Label
        RxNoiseFiguredBEditField_2      matlab.ui.control.NumericEditField
        RxNoiseFiguredBEditField_2Label  matlab.ui.control.Label
        MobileRangemEditField_2         matlab.ui.control.NumericEditField
        MobileRangemLabel               matlab.ui.control.Label
        MeanRadarCrossSectiondBsmEditField  matlab.ui.control.NumericEditField
        MeanRadarCrossSectiondBsmEditFieldLabel  matlab.ui.control.Label
        TargetStatisticalModelDropDown_2  matlab.ui.control.DropDown
        TargetStatisticalModelDropDown_2Label  matlab.ui.control.Label
        ChannelParametersPanel          matlab.ui.container.Panel
        NumberofRaysEditField           matlab.ui.control.NumericEditField
        NumberofRaysEditFieldLabel      matlab.ui.control.Label
        SystemNoiseTemperatureKEditField  matlab.ui.control.NumericEditField
        SystemNoiseTemperatureKEditFieldLabel  matlab.ui.control.Label
        NumberofClustersEditField       matlab.ui.control.NumericEditField
        NumberofClustersEditFieldLabel  matlab.ui.control.Label
        RayArrivalRate1nsecEditField    matlab.ui.control.NumericEditField
        RayArrivalRate1nsecEditFieldLabel  matlab.ui.control.Label
        PathLossExponentEditField       matlab.ui.control.NumericEditField
        PathLossExponentEditFieldLabel  matlab.ui.control.Label
        ClusterArrivalRate1nsecEditField  matlab.ui.control.NumericEditField
        ClusterArrivalRate1nsecEditFieldLabel  matlab.ui.control.Label
        RayDecayFactornsecEditField     matlab.ui.control.NumericEditField
        RayDecayFactornsecEditFieldLabel  matlab.ui.control.Label
        ClusterDecayFactornsecEditField  matlab.ui.control.NumericEditField
        ClusterDecayFactornsecEditFieldLabel  matlab.ui.control.Label
        DCFARParametersPanel            matlab.ui.container.Panel
        GuardBandSizeEditField          matlab.ui.control.EditField
        GuardBandSizeEditFieldLabel     matlab.ui.control.Label
        TrainingBandSizeEditField       matlab.ui.control.EditField
        TrainingBandSizeEditFieldLabel  matlab.ui.control.Label
        ProbabilityofFalseAlarmEditField_2  matlab.ui.control.NumericEditField
        ProbabilityofFalseAlarmEditField_2Label  matlab.ui.control.Label
        CFARTypeDropDown                matlab.ui.control.DropDown
        CFARTypeDropDownLabel           matlab.ui.control.Label
        WaveformParametersPanel         matlab.ui.container.Panel
        ChannelEqualizationMethodDropDown  matlab.ui.control.DropDown
        ChannelEqualizationMethodDropDownLabel  matlab.ui.control.Label
        CyclicPrefixRateDropDown        matlab.ui.control.DropDown
        CyclicPrefixRateDropDownLabel   matlab.ui.control.Label
        NumberofSymbolsDropDown         matlab.ui.control.DropDown
        NumberofSymbolsDropDownLabel    matlab.ui.control.Label
        NumberofSubcarriersDropDown     matlab.ui.control.DropDown
        NumberofSubcarriersDropDownLabel  matlab.ui.control.Label
        BandwidthGHzEditField_2         matlab.ui.control.NumericEditField
        BandwidthGHzEditField_2Label    matlab.ui.control.Label
        CodeRateDropDown                matlab.ui.control.DropDown
        CodeRateDropDownLabel           matlab.ui.control.Label
        ChannelCodingTypeDropDown_2     matlab.ui.control.DropDown
        ChannelCodingTypeDropDown_2Label  matlab.ui.control.Label
        ModulationOrderQAMDropDown      matlab.ui.control.DropDown
        ModulationOrderQAMDropDownLabel  matlab.ui.control.Label
        WaveformTypeDropDown_2          matlab.ui.control.DropDown
        WaveformTypeDropDown_2Label     matlab.ui.control.Label
        CarrierFrequencyGHzEditField_2  matlab.ui.control.NumericEditField
        CarrierFrequencyGHzEditField_2Label  matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        EchoSensingwithVirtualArrayTab  matlab.ui.container.Tab
        NumericalSimulationResultsTextArea_2  matlab.ui.control.TextArea
        ShowNumericalResultsButton_2    matlab.ui.control.Button
        RangeAngleImagingPanel          matlab.ui.container.Panel
        PlotButton_6                    matlab.ui.control.Button
        UIAxes3_3                       matlab.ui.control.UIAxes
        RangeVelocityImagingwith2DCACFARPanel  matlab.ui.container.Panel
        PlotButton_5                    matlab.ui.control.Button
        UIAxes3_2                       matlab.ui.control.UIAxes
        RangeVelocityImagingPanel       matlab.ui.container.Panel
        PlotButton_4                    matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        RunSimulationButton_2           matlab.ui.control.Button
        SensingAidedDownlinkCommunicationTab  matlab.ui.container.Tab
        ShowNumericalResultsButton      matlab.ui.control.Button
        NumericalSimulationResultsTextArea  matlab.ui.control.TextArea
        DelaydomainChannelResponseofNLoSPanel  matlab.ui.container.Panel
        PlotButton_3                    matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        RunSimulationButton             matlab.ui.control.Button
        RxConstellationPanel            matlab.ui.container.Panel
        PlotButton_2                    matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        TxConstellationPanel            matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 界面居中
            position_screen = get(0,"ScreenSize"); 
            position_app = app.UIFigure.Position;
            position_app(1) = (position_screen(3)-position_app(3))/2;
            position_app(2) = (position_screen(4)-position_app(4))/2;
            app.UIFigure.Position = position_app;
        end

        % Value changed function: CarrierFrequencyGHzEditField_2
        function CarrierFrequencyGHzEditField_2ValueChanged(app, event)

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % Waveform Parameters
            assignin('base', 'fc', app.CarrierFrequencyGHzEditField_2.Value * 1e9);
            assignin('base', 'BW', app.BandwidthGHzEditField_2.Value * 1e9);
            assignin('base', 'WaveformType', app.WaveformTypeDropDown_2.Value);
            assignin('base', 'Nc', str2num(app.NumberofSubcarriersDropDown.Value));
            assignin('base', 'ModOrder', str2num(app.ModulationOrderQAMDropDown.Value));
            assignin('base', 'Nsym', str2num(app.NumberofSymbolsDropDown.Value));
            assignin('base', 'ChannelCodingType', app.ChannelCodingTypeDropDown_2.Value);
            assignin('base', 'CodeRate', str2num(app.CodeRateDropDown.Value));
            assignin('base', 'CPRatio', str2num(app.CyclicPrefixRateDropDown.Value));
            assignin('base', 'ChannelEqualizationMethod', app.ChannelEqualizationMethodDropDown.Value);
            % 2D-CFAR Parameters
            assignin('base', 'CFARMethod', app.CFARTypeDropDown.Value);
            assignin('base', 'Pfa', app.ProbabilityofFalseAlarmEditField_2.Value);
            assignin('base', 'GuardBandSize', str2num(app.GuardBandSizeEditField.Value));
            assignin('base', 'TrainingBandSize', str2num(app.TrainingBandSizeEditField.Value));
            % Channel Parameters
            assignin('base', 'PLE', app.PathLossExponentEditField.Value);
            assignin('base', 'ClusterArrivalRate', app.ClusterArrivalRate1nsecEditField.Value);
            assignin('base', 'RayArrivalRate', app.RayArrivalRate1nsecEditField.Value);
            assignin('base', 'ClusterDecayFactor', app.ClusterDecayFactornsecEditField.Value);
            assignin('base', 'RayDecayFactor', app.RayDecayFactornsecEditField.Value);
            assignin('base', 'NumClusters', app.NumberofClustersEditField.Value);
            assignin('base', 'NumRays', app.NumberofRaysEditField.Value);
            % ISAC System Parameters
            assignin('base', 'ArrayModel', app.ArrayModelDropDown.Value);
            assignin('base', 'NTxISAC', str2num(app.NumberofTxAntennasDropDown.Value));
            assignin('base', 'NRxISAC', str2num(app.NumberofRxAntennasDropDown.Value));
            assignin('base', 'PTxISAC', app.TxPowerperTxAntennadBmEditField.Value);
            assignin('base', 'GTxISAC', app.TxGainperTxAntennadBEditField.Value);
            assignin('base', 'GRxISAC', app.RxGainperRxAntennadBEditField.Value);
            assignin('base', 'NFigISAC', app.RxNoiseFiguredBEditField.Value);
            assignin('base', 'Tsys', app.SystemNoiseTemperatureKEditField.Value);
            assignin('base', 'VelocityISAC', str2num(app.DAbsoluteVelocitymsEditField_2.Value));
            % UE Parameters
            assignin('base', 'TargetStatisticalModel', app.TargetStatisticalModelDropDown_2.Value);
            assignin('base', 'NRxUE', str2num(app.NumberofRxAntennasDropDown_2.Value));
            assignin('base', 'GRxUE', app.RxGainperRxAntennadBEditField_2.Value);
            assignin('base', 'NFigUE', app.RxNoiseFiguredBEditField_2.Value);
            assignin('base', 'RCSdBsm', app.MeanRadarCrossSectiondBsmEditField.Value);
            assignin('base', 'Range', app.MobileRangemEditField_2.Value);
            assignin('base', 'Velocity', str2num(app.DAbsoluteVelocitymsEditField.Value));
            assignin('base', 'Angle', str2num(app.AzimuthAngleEditField.Value));
            run THz_MIMO_OFDM_Com.p;
            message = msgbox('The simulation has been successfully completed!', 'Success');
           
        end

        % Value changed function: ModulationOrderQAMDropDown
        function ModulationOrderQAMDropDownValueChanged(app, event)
    
        end

        % Value changed function: CodeRateDropDown
        function CodeRateDropDownValueChanged(app, event)
     
        end

        % Value changed function: BandwidthGHzEditField_2
        function BandwidthGHzEditField_2ValueChanged(app, event)

        end

        % Value changed function: WaveformTypeDropDown_2
        function WaveformTypeDropDown_2ValueChanged(app, event)
     
        end

        % Value changed function: NumberofSubcarriersDropDown
        function NumberofSubcarriersDropDownValueChanged(app, event)

        end

        % Value changed function: NumberofSymbolsDropDown
        function NumberofSymbolsDropDownValueChanged(app, event)

        end

        % Value changed function: ChannelCodingTypeDropDown_2
        function ChannelCodingTypeDropDown_2ValueChanged(app, event)

        end

        % Value changed function: CyclicPrefixRateDropDown
        function CyclicPrefixRateDropDownValueChanged(app, event)
           
        end

        % Value changed function: ChannelEqualizationMethodDropDown
        function ChannelEqualizationMethodDropDownValueChanged(app, event)
         
        end

        % Value changed function: CFARTypeDropDown
        function CFARTypeDropDownValueChanged(app, event)
            
        end

        % Value changed function: ProbabilityofFalseAlarmEditField_2
        function ProbabilityofFalseAlarmEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: GuardBandSizeEditField
        function GuardBandSizeEditFieldValueChanged(app, event)
            
        end

        % Value changed function: TrainingBandSizeEditField
        function TrainingBandSizeEditFieldValueChanged(app, event)
            
        end

        % Value changed function: PathLossExponentEditField
        function PathLossExponentEditFieldValueChanged(app, event)
            
        end

        % Value changed function: ClusterArrivalRate1nsecEditField
        function ClusterArrivalRate1nsecEditFieldValueChanged(app, event)
         
        end

        % Value changed function: RayArrivalRate1nsecEditField
        function RayArrivalRate1nsecEditFieldValueChanged(app, event)
           
        end

        % Value changed function: ClusterDecayFactornsecEditField
        function ClusterDecayFactornsecEditFieldValueChanged(app, event)
           
        end

        % Value changed function: RayDecayFactornsecEditField
        function RayDecayFactornsecEditFieldValueChanged(app, event)
            
        end

        % Value changed function: NumberofTxAntennasDropDown
        function NumberofTxAntennasDropDownValueChanged(app, event)
            
        end

        % Value changed function: NumberofRxAntennasDropDown
        function NumberofRxAntennasDropDownValueChanged(app, event)
            
        end

        % Value changed function: NumberofRxAntennasDropDown_2
        function NumberofRxAntennasDropDown_2ValueChanged(app, event)
           
        end

        % Value changed function: TxPowerperTxAntennadBmEditField
        function TxPowerperTxAntennadBmEditFieldValueChanged(app, event)
            
        end

        % Value changed function: TxGainperTxAntennadBEditField
        function TxGainperTxAntennadBEditFieldValueChanged(app, event)
            
        end

        % Value changed function: RxGainperRxAntennadBEditField
        function RxGainperRxAntennadBEditFieldValueChanged(app, event)
            
        end

        % Value changed function: RxNoiseFiguredBEditField
        function RxNoiseFiguredBEditFieldValueChanged(app, event)
            
        end

        % Value changed function: TargetStatisticalModelDropDown_2
        function TargetStatisticalModelDropDown_2ValueChanged(app, event)
            
        end

        % Value changed function: RxGainperRxAntennadBEditField_2
        function RxGainperRxAntennadBEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: RxNoiseFiguredBEditField_2
        function RxNoiseFiguredBEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: MeanRadarCrossSectiondBsmEditField
        function MeanRadarCrossSectiondBsmEditFieldValueChanged(app, event)
           
        end

        % Value changed function: MobileRangemEditField_2
        function MobileRangemEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: DAbsoluteVelocitymsEditField
        function DAbsoluteVelocitymsEditFieldValueChanged(app, event)
            
        end

        % Value changed function: AzimuthAngleEditField
        function AzimuthAngleEditFieldValueChanged(app, event)
          
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plot(app.UIAxes, real(evalin('base', 'QAMTx')), imag(evalin('base', 'QAMTx')), '.', 'MarkerSize', 8);
            xlim(app.UIAxes, [-1.5 1.5]);
            ylim(app.UIAxes, [-1.5 1.5]);

        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            plot(app.UIAxes_2, real(evalin('base', 'SymbolsRx')), imag(evalin('base', 'SymbolsRx')), '.', 'MarkerSize', 4);
            xlim(app.UIAxes_2, [-1.5 1.5]);
            ylim(app.UIAxes_2, [-1.5 1.5]);

        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Value changed function: NumericalSimulationResultsTextArea
        function NumericalSimulationResultsTextAreaValueChanged(app, event)

        end

        % Button pushed function: ShowNumericalResultsButton
        function ShowNumericalResultsButtonPushed(app, event)
            PAPRdB = evalin('base', 'BasebandPAPR');
            RFPAPRdB = evalin('base', 'RFPAPR');
            BER = evalin('base', 'BER');
            rmsEVMdB = evalin('base', 'rmsEVMdB');
            effDateRate = evalin('base', 'effDateRate');
            SE = evalin('base', 'SE');
            % 32表示空格, 10表示换行
            app.NumericalSimulationResultsTextArea.Value = strcat('Baseband PAPR =',32,sprintf('%.2f',PAPRdB),' dB',10, ...
            'BER =',32,BER,';',32,'RMS EVM =',32,sprintf('%.2f',rmsEVMdB),' dB',10, ...
            'Normalized SE =',32,sprintf('%.2f',SE),' bit/s/Hz;',32,'Effective Date Rate =',32,sprintf('%.2f',effDateRate*1e-9),' Gbps');
            app.NumericalSimulationResultsTextArea.FontSize = 14;
            app.NumericalSimulationResultsTextArea.FontName = 'Monospaced';
        end

        % Button pushed function: RunSimulationButton_2
        function RunSimulationButton_2Pushed(app, event)
            % Waveform Parameters
            assignin('base', 'fc', app.CarrierFrequencyGHzEditField_2.Value * 1e9);
            assignin('base', 'BW', app.BandwidthGHzEditField_2.Value * 1e9);
            assignin('base', 'WaveformType', app.WaveformTypeDropDown_2.Value);
            assignin('base', 'Nc', str2num(app.NumberofSubcarriersDropDown.Value));
            assignin('base', 'ModOrder', str2num(app.ModulationOrderQAMDropDown.Value));
            assignin('base', 'Nsym', str2num(app.NumberofSymbolsDropDown.Value));
            assignin('base', 'ChannelCodingType', app.ChannelCodingTypeDropDown_2.Value);
            assignin('base', 'CodeRate', str2num(app.CodeRateDropDown.Value));
            assignin('base', 'CPRatio', str2num(app.CyclicPrefixRateDropDown.Value));
            assignin('base', 'ChannelEqualizationMethod', app.ChannelEqualizationMethodDropDown.Value);
            % 2D-CFAR Parameters
            assignin('base', 'CFARMethod', app.CFARTypeDropDown.Value);
            assignin('base', 'Pfa', app.ProbabilityofFalseAlarmEditField_2.Value);
            assignin('base', 'GuardBandSize', str2num(app.GuardBandSizeEditField.Value));
            assignin('base', 'TrainingBandSize', str2num(app.TrainingBandSizeEditField.Value));
            % Channel Parameters
            assignin('base', 'PLE', app.PathLossExponentEditField.Value);
            assignin('base', 'ClusterArrivalRate', app.ClusterArrivalRate1nsecEditField.Value);
            assignin('base', 'RayArrivalRate', app.RayArrivalRate1nsecEditField.Value);
            assignin('base', 'ClusterDecayFactor', app.ClusterDecayFactornsecEditField.Value);
            assignin('base', 'RayDecayFactor', app.RayDecayFactornsecEditField.Value);
            assignin('base', 'NumClusters', app.NumberofClustersEditField.Value);
            assignin('base', 'NumRays', app.NumberofRaysEditField.Value);
            % ISAC System Parameters
            assignin('base', 'ArrayModel', app.ArrayModelDropDown.Value);
            assignin('base', 'NTxISAC', str2num(app.NumberofTxAntennasDropDown.Value));
            assignin('base', 'NRxISAC', str2num(app.NumberofRxAntennasDropDown.Value));
            assignin('base', 'PTxISAC', app.TxPowerperTxAntennadBmEditField.Value);
            assignin('base', 'GTxISAC', app.TxGainperTxAntennadBEditField.Value);
            assignin('base', 'GRxISAC', app.RxGainperRxAntennadBEditField.Value);
            assignin('base', 'NFigISAC', app.RxNoiseFiguredBEditField.Value);
            assignin('base', 'Tsys', app.SystemNoiseTemperatureKEditField.Value);
            assignin('base', 'VelocityISAC', str2num(app.DAbsoluteVelocitymsEditField_2.Value));
            % UE Parameters
            assignin('base', 'TargetStatisticalModel', app.TargetStatisticalModelDropDown_2.Value);
            assignin('base', 'NRxUE', str2num(app.NumberofRxAntennasDropDown_2.Value));
            assignin('base', 'GRxUE', app.RxGainperRxAntennadBEditField_2.Value);
            assignin('base', 'NFigUE', app.RxNoiseFiguredBEditField_2.Value);
            assignin('base', 'RCSdBsm', app.MeanRadarCrossSectiondBsmEditField.Value);
            assignin('base', 'Range', app.MobileRangemEditField_2.Value);
            assignin('base', 'Velocity', str2num(app.DAbsoluteVelocitymsEditField.Value));
            assignin('base', 'Angle', str2num(app.AzimuthAngleEditField.Value));
            run THz_MIMO_OFDM_Sen.p;
            message = msgbox('The simulation has been successfully completed!', 'Success');
            
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            velgrid = evalin('base', 'velgrid');
            rnggrid = evalin('base', 'rnggrid');
            VelGrid = evalin('base', 'VelGrid');
            RngGrid = evalin('base', 'RngGrid');
            RngVelPowIncoInt = evalin('base', 'RngVelPowIncoInt');
            surf(app.UIAxes3, VelGrid, RngGrid, RngVelPowIncoInt.');
            view(app.UIAxes3, 0, 90);
            axis(app.UIAxes3, 'square');
            shading(app.UIAxes3, 'interp');
            padding = 6;
            RangeRx = evalin('base','thz.mobileRange');
            xlim(app.UIAxes3, [ceil(max(evalin('base','thz.RelVel')-padding, min(velgrid))) floor(min(evalin('base','thz.RelVel')+padding, max(velgrid)))]);
            ylim(app.UIAxes3, [floor(max(0, RangeRx-padding)) floor(min(RangeRx+padding, evalin('base','thz.MaxRange')))]);
            % xlim(app.UIAxes3, [12 16]);
            % ylim(app.UIAxes3, [28 32]);
            % set(app.UIAxes3, 'YTick', 28:1:32);
            cbar = colorbar(app.UIAxes3);
            cbar.Label.String = 'Normalized Amplitude (dB)';
            cbar.Label.FontSize = 11;
            set(app.UIAxes3,'LineWidth',1,'YDir','normal');
            set(cbar,'FontName','Times New Roman','LineWidth', 0.5);
            clim(app.UIAxes3,[-40 0]);
        end

        % Button pushed function: PlotButton_5
        function PlotButton_5Pushed(app, event)
            velgrid = evalin('base', 'velgrid');
            rnggrid = evalin('base', 'rnggrid');
            VelGrid = evalin('base', 'VelGrid');
            RngGrid = evalin('base', 'RngGrid');
            RngVelCFAR = evalin('base', 'RngVelCFAR');
            imagesc(app.UIAxes3_2, velgrid, rnggrid, RngVelCFAR);
            axis(app.UIAxes3_2, 'square');
            shading(app.UIAxes3_2, 'interp');
            padding = 6;
            RangeRx = evalin('base','thz.mobileRange');
            xlim(app.UIAxes3_2, [ceil(max(evalin('base','thz.RelVel')-padding, min(velgrid))) floor(min(evalin('base','thz.RelVel')+padding, max(velgrid)))]);
            ylim(app.UIAxes3_2, [floor(max(0, RangeRx-padding)) floor(min(RangeRx+padding, evalin('base','thz.MaxRange')))]);
            % xlim(app.UIAxes3_2, [12 16]);
            % ylim(app.UIAxes3_2, [28 32]);
            % set(app.UIAxes3_2, 'YTick', 28:1:32);
            cbar = colorbar(app.UIAxes3_2);
            cbar.Label.String = 'Normalized Amplitude (dB)';
            cbar.Label.FontSize = 11;
            set(app.UIAxes3_2,'LineWidth',1,'YDir','normal');
            set(cbar,'FontName','Times New Roman','LineWidth', 0.5);
            clim(app.UIAxes3_2,[-40 0]);
        end

        % Button pushed function: PlotButton_6
        function PlotButton_6Pushed(app, event)
            anggrid = evalin('base', 'anggrid');
            AngGrid = evalin('base', 'AngGrid');
            RngGrid2 = evalin('base', 'RngGrid2');
            RngAngPow = evalin('base', 'RngAngPow');
            surf(app.UIAxes3_3, AngGrid, RngGrid2, RngAngPow.');
            view(app.UIAxes3_3, 0, 90);
            axis(app.UIAxes3_3, 'square');
            shading(app.UIAxes3_3, 'interp');
            rngpadding = 6;
            angpadding = 18;
            thz = evalin('base','thz');
            RangeRx = thz.mobileRange;
            xlim(app.UIAxes3_3, [floor(max(-60,thz.mobileAngle(1)-angpadding)) floor(min(thz.mobileAngle(1)+angpadding,60))]);
            ylim(app.UIAxes3_3, [floor(max(0, RangeRx-rngpadding)) floor(min(RangeRx+rngpadding, evalin('base','thz.MaxRange')))]);
            % xlim(app.UIAxes3_3, [0 40]);
            % ylim(app.UIAxes3_3, [28 32]);
            % set(app.UIAxes3_3, 'YTick', 28:1:32);
            cbar = colorbar(app.UIAxes3_3);
            cbar.Label.String = 'Normalized Amplitude (dB)';
            cbar.Label.FontSize = 11;
            set(app.UIAxes3_3,'LineWidth',1,'YDir','normal');
            set(cbar,'FontName','Times New Roman','LineWidth', 0.5);
            clim(app.UIAxes3_3,[-40 0]);
        end

        % Value changed function: NumericalSimulationResultsTextArea_2
        function NumericalSimulationResultsTextArea_2ValueChanged(app, event)
           
        end

        % Button pushed function: ShowNumericalResultsButton_2
        function ShowNumericalResultsButton_2Pushed(app, event)
            RngEstimate = evalin('base','RngEstimate');
            VelEstimate = evalin('base','VelEstimate');
            AngEstimate = evalin('base','AngEstimate');
            PosEstimate = evalin('base','PosEstimate');
            PosError = evalin('base','PosError');
            thz = evalin('base','thz');
            % 32表示空格, 10表示换行
            app.NumericalSimulationResultsTextArea_2.Value = strcat('Range =',32,sprintf('%.2f', thz.mobileRange),' m',',',32,'Range estimation =',32, ...
                sprintf('%.2f', RngEstimate),' m',';',32,'Azimuth angle =',32, sprintf('%.2f', thz.mobileAngle(1)),'°',',',32,'Azimuth angle estimation =',32, ...
                sprintf('%.2f', AngEstimate),'°',10, 'Relative radial velocity =',32,sprintf('%.2f', thz.RelVel),' m/s',',',32,'Relative radial velocity estimation =',32, ...
                sprintf('%.2f', VelEstimate),' m/s',10, 'Target position =',32,'[',sprintf('%.2f', thz.PosRx(1)),',',sprintf('%.2f', thz.PosRx(2)),']',' m',',',32,'Target position estimation =',32, ...
                '[',sprintf('%.2f', PosEstimate(1)),',',sprintf('%.2f', PosEstimate(2)),']',' m',',',32,'Positioning error =',32,sprintf('%.2f', PosError),' m');
            app.NumericalSimulationResultsTextArea_2.FontSize = 14;
            app.NumericalSimulationResultsTextArea_2.FontName = 'Monospaced';
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            Tau_NLoS = evalin('base','Tau_NLoS');
            GainsNLoS = evalin('base','GainsNLoS');
            Tau_LoS = evalin('base','Tau_LoS');
            str = [];
            for idx = 1:size(Tau_NLoS,1)
                str = string([str,strcat('Cluster No.',num2str(idx))]);
            end
            stem(app.UIAxes2, Tau_NLoS.' * 1e9, GainsNLoS.', 'LineWidth', 1.5, 'MarkerSize', 6);
            legend(app.UIAxes2, str, 'fontsize', 10);
            xlim(app.UIAxes2, [floor(Tau_LoS*1e9/100)*100 ceil(max(max(Tau_NLoS))*1e9/100)*100]);
            ylim(app.UIAxes2, [0 max(max(GainsNLoS))]);
        end

        % Value changed function: SystemNoiseTemperatureKEditField
        function SystemNoiseTemperatureKEditFieldValueChanged(app, event)
            
        end

        % Value changed function: NumberofClustersEditField
        function NumberofClustersEditFieldValueChanged(app, event)

        end

        % Value changed function: DAbsoluteVelocitymsEditField_2
        function DAbsoluteVelocitymsEditField_2ValueChanged(app, event)
            value = app.DAbsoluteVelocitymsEditField_2.Value;
            
        end

        % Value changed function: NumberofRaysEditField
        function NumberofRaysEditFieldValueChanged(app, event)
            value = app.NumberofRaysEditField.Value;
            
        end

        % Value changed function: ArrayModelDropDown
        function ArrayModelDropDownValueChanged(app, event)
            value = app.ArrayModelDropDown.Value;
            
        end

        % Button down function: UIAxes3_2
        function UIAxes3_2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3_3
        function UIAxes3_3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.EchoSensingwithVirtualArrayTab = uitab(app.TabGroup2);
            app.EchoSensingwithVirtualArrayTab.Title = 'Echo Sensing with Virtual Array';
            app.RunSimulationButton_2 = uibutton(app.EchoSensingwithVirtualArrayTab, 'push');
            app.RunSimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButton_2Pushed, true);
            app.RunSimulationButton_2.Text = 'Run Simulation';
            app.RangeVelocityImagingPanel = uipanel(app.EchoSensingwithVirtualArrayTab);
            app.RangeVelocityImagingPanel.Title = 'Range-Velocity Imaging';
            app.UIAxes3 = uiaxes(app.RangeVelocityImagingPanel);
            xlabel(app.UIAxes3, 'Relative Radial Velocity (m/s)')
            ylabel(app.UIAxes3, 'Range (m)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [-40 40];
            app.UIAxes3.YLim = [0 80];
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.PlotButton_4 = uibutton(app.RangeVelocityImagingPanel, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.RangeVelocityImagingwith2DCACFARPanel = uipanel(app.EchoSensingwithVirtualArrayTab);
            app.RangeVelocityImagingwith2DCACFARPanel.Title = 'Range-Velocity Imaging with 2D CA-CFAR';
            app.UIAxes3_2 = uiaxes(app.RangeVelocityImagingwith2DCACFARPanel);
            xlabel(app.UIAxes3_2, 'Relative Radial Velocity (m/s)')
            ylabel(app.UIAxes3_2, 'Range (m)')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.XLim = [-40 40];
            app.UIAxes3_2.YLim = [0 80];
            app.UIAxes3_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes3_2ButtonDown, true);
            app.PlotButton_5 = uibutton(app.RangeVelocityImagingwith2DCACFARPanel, 'push');
            app.PlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_5Pushed, true);
            app.PlotButton_5.Text = 'Plot';
            app.RangeAngleImagingPanel = uipanel(app.EchoSensingwithVirtualArrayTab);
            app.RangeAngleImagingPanel.Title = 'Range-Angle Imaging';
            app.UIAxes3_3 = uiaxes(app.RangeAngleImagingPanel);
            xlabel(app.UIAxes3_3, 'Azimuth Angle (°)')
            ylabel(app.UIAxes3_3, 'Range (m)')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_3.XLim = [-60 60];
            app.UIAxes3_3.YLim = [0 80];
            app.UIAxes3_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3_3ButtonDown, true);
            app.PlotButton_6 = uibutton(app.RangeAngleImagingPanel, 'push');
            app.PlotButton_6.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_6Pushed, true);
            app.PlotButton_6.Text = 'Plot';
            app.ShowNumericalResultsButton_2 = uibutton(app.EchoSensingwithVirtualArrayTab, 'push');
            app.ShowNumericalResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowNumericalResultsButton_2Pushed, true);
            app.ShowNumericalResultsButton_2.Text = 'Show Numerical Results ';
            app.NumericalSimulationResultsTextArea_2 = uitextarea(app.EchoSensingwithVirtualArrayTab);
            app.NumericalSimulationResultsTextArea_2.ValueChangedFcn = createCallbackFcn(app, @NumericalSimulationResultsTextArea_2ValueChanged, true);
            app.NumericalSimulationResultsTextArea_2.Value = {''; ''; ''};
            app.SensingAidedDownlinkCommunicationTab = uitab(app.TabGroup2);
            app.SensingAidedDownlinkCommunicationTab.Title = 'Sensing-Aided Downlink Communication';
            app.TxConstellationPanel = uipanel(app.SensingAidedDownlinkCommunicationTab);
            app.TxConstellationPanel.Title = 'Tx Constellation';
            app.UIAxes = uiaxes(app.TxConstellationPanel);
            xlabel(app.UIAxes, 'In-Phase Amplitude')
            ylabel(app.UIAxes, 'Quadrature Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1.5 1.5];
            app.UIAxes.YLim = [-1.5 1.5];
            app.UIAxes.XTick = [-1.5 -1 -0.5 0 0.5 1 1.5];
            app.UIAxes.XTickLabel = {'-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'};
            app.UIAxes.YTick = [-1.5 -1 -0.5 0 0.5 1 1.5];
            app.UIAxes.YTickLabel = {'-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlotButton = uibutton(app.TxConstellationPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RxConstellationPanel = uipanel(app.SensingAidedDownlinkCommunicationTab);
            app.RxConstellationPanel.Title = 'Rx Constellation';
            app.UIAxes_2 = uiaxes(app.RxConstellationPanel);
            xlabel(app.UIAxes_2, 'In-Phase Amplitude')
            ylabel(app.UIAxes_2, 'Quadrature Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-1.5 1.5];
            app.UIAxes_2.YLim = [-1.5 1.5];
            app.UIAxes_2.XTick = [-1.5 -1 -0.5 0 0.5 1 1.5];
            app.UIAxes_2.XTickLabel = {'-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'};
            app.UIAxes_2.YTick = [-1.5 -1 -0.5 0 0.5 1 1.5];
            app.UIAxes_2.YTickLabel = {'-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'};
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.PlotButton_2 = uibutton(app.RxConstellationPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.RunSimulationButton = uibutton(app.SensingAidedDownlinkCommunicationTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.DelaydomainChannelResponseofNLoSPanel = uipanel(app.SensingAidedDownlinkCommunicationTab);
            app.DelaydomainChannelResponseofNLoSPanel.Title = 'Delay-domain Channel Response of NLoS';
            app.UIAxes2 = uiaxes(app.DelaydomainChannelResponseofNLoSPanel);
            xlabel(app.UIAxes2, 'Delay of NLoS (ns)')
            ylabel(app.UIAxes2, 'Amplitude of NLoS Path Gains')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 1000];
            app.UIAxes2.YLim = [0 1e-05];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.PlotButton_3 = uibutton(app.DelaydomainChannelResponseofNLoSPanel, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.NumericalSimulationResultsTextArea = uitextarea(app.SensingAidedDownlinkCommunicationTab);
            app.NumericalSimulationResultsTextArea.ValueChangedFcn = createCallbackFcn(app, @NumericalSimulationResultsTextAreaValueChanged, true);
            app.ShowNumericalResultsButton = uibutton(app.SensingAidedDownlinkCommunicationTab, 'push');
            app.ShowNumericalResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowNumericalResultsButtonPushed, true);
            app.ShowNumericalResultsButton.Text = 'Show Numerical Results ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersInitializationTab = uitab(app.TabGroup);
            app.ParametersInitializationTab.Title = 'Parameters Initialization';
            app.WaveformParametersPanel = uipanel(app.ParametersInitializationTab);
            app.WaveformParametersPanel.Title = 'Waveform Parameters';
            app.CarrierFrequencyGHzEditField_2Label = uilabel(app.WaveformParametersPanel);
            app.CarrierFrequencyGHzEditField_2Label.Text = 'Carrier Frequency (GHz)';
            app.CarrierFrequencyGHzEditField_2 = uieditfield(app.WaveformParametersPanel, 'numeric');
            app.CarrierFrequencyGHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyGHzEditField_2ValueChanged, true);
            app.CarrierFrequencyGHzEditField_2.Value = 252;
            app.WaveformTypeDropDown_2Label = uilabel(app.WaveformParametersPanel);
            app.WaveformTypeDropDown_2Label.Text = 'Waveform Type';
            app.WaveformTypeDropDown_2 = uidropdown(app.WaveformParametersPanel);
            app.WaveformTypeDropDown_2.Items = {'OFDM', 'DFT-s-OFDM'};
            app.WaveformTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @WaveformTypeDropDown_2ValueChanged, true);
            app.WaveformTypeDropDown_2.Value = 'OFDM';
            app.ModulationOrderQAMDropDownLabel = uilabel(app.WaveformParametersPanel);
            app.ModulationOrderQAMDropDownLabel.Text = 'Modulation Order (QAM)';
            app.ModulationOrderQAMDropDown = uidropdown(app.WaveformParametersPanel);
            app.ModulationOrderQAMDropDown.Items = {'4', '16', '64', '256'};
            app.ModulationOrderQAMDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationOrderQAMDropDownValueChanged, true);
            app.ModulationOrderQAMDropDown.Value = '16';
            app.ChannelCodingTypeDropDown_2Label = uilabel(app.WaveformParametersPanel);
            app.ChannelCodingTypeDropDown_2Label.Text = 'Channel Coding Type';
            app.ChannelCodingTypeDropDown_2 = uidropdown(app.WaveformParametersPanel);
            app.ChannelCodingTypeDropDown_2.Items = {'None', 'Convolutional'};
            app.ChannelCodingTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelCodingTypeDropDown_2ValueChanged, true);
            app.ChannelCodingTypeDropDown_2.Value = 'Convolutional';
            app.CodeRateDropDownLabel = uilabel(app.WaveformParametersPanel);
            app.CodeRateDropDownLabel.Text = 'Code Rate';
            app.CodeRateDropDown = uidropdown(app.WaveformParametersPanel);
            app.CodeRateDropDown.Items = {'1/3', '1/2', '2/3', '3/4', '5/6', '7/8', '1'};
            app.CodeRateDropDown.ValueChangedFcn = createCallbackFcn(app, @CodeRateDropDownValueChanged, true);
            app.CodeRateDropDown.Value = '7/8';
            app.BandwidthGHzEditField_2Label = uilabel(app.WaveformParametersPanel);
            app.BandwidthGHzEditField_2Label.Text = 'Bandwidth (GHz)';
            app.BandwidthGHzEditField_2 = uieditfield(app.WaveformParametersPanel, 'numeric');
            app.BandwidthGHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @BandwidthGHzEditField_2ValueChanged, true);
            app.BandwidthGHzEditField_2.Value = 2.16;
            app.NumberofSubcarriersDropDownLabel = uilabel(app.WaveformParametersPanel);
            app.NumberofSubcarriersDropDownLabel.Text = 'Number of Subcarriers ';
            app.NumberofSubcarriersDropDown = uidropdown(app.WaveformParametersPanel);
            app.NumberofSubcarriersDropDown.Items = {'128', '256', '512', '1024', '2048', '4096', '8192'};
            app.NumberofSubcarriersDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofSubcarriersDropDownValueChanged, true);
            app.NumberofSubcarriersDropDown.Value = '4096';
            app.NumberofSymbolsDropDownLabel = uilabel(app.WaveformParametersPanel);
            app.NumberofSymbolsDropDownLabel.Text = 'Number of Symbols ';
            app.NumberofSymbolsDropDown = uidropdown(app.WaveformParametersPanel);
            app.NumberofSymbolsDropDown.Items = {'16', '32', '64', '128', '256', '512', '1024', '2048', '4096', '8192'};
            app.NumberofSymbolsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofSymbolsDropDownValueChanged, true);
            app.NumberofSymbolsDropDown.Value = '2048';
            app.CyclicPrefixRateDropDownLabel = uilabel(app.WaveformParametersPanel);
            app.CyclicPrefixRateDropDownLabel.Text = 'Cyclic Prefix Rate';
            app.CyclicPrefixRateDropDown = uidropdown(app.WaveformParametersPanel);
            app.CyclicPrefixRateDropDown.Items = {'1/32', '1/16', '1/8', '1/4'};
            app.CyclicPrefixRateDropDown.ValueChangedFcn = createCallbackFcn(app, @CyclicPrefixRateDropDownValueChanged, true);
            app.CyclicPrefixRateDropDown.Value = '1/4';
            app.ChannelEqualizationMethodDropDownLabel = uilabel(app.WaveformParametersPanel);
            app.ChannelEqualizationMethodDropDownLabel.Text = 'Channel Equalization Method';
            app.ChannelEqualizationMethodDropDown = uidropdown(app.WaveformParametersPanel);
            app.ChannelEqualizationMethodDropDown.Items = {'MMSE', 'ZF'};
            app.ChannelEqualizationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelEqualizationMethodDropDownValueChanged, true);
            app.ChannelEqualizationMethodDropDown.Value = 'MMSE';
            app.DCFARParametersPanel = uipanel(app.ParametersInitializationTab);
            app.DCFARParametersPanel.Title = '2D-CFAR Parameters';
            app.CFARTypeDropDownLabel = uilabel(app.DCFARParametersPanel);
            app.CFARTypeDropDownLabel.Text = 'CFAR Type';
            app.CFARTypeDropDown = uidropdown(app.DCFARParametersPanel);
            app.CFARTypeDropDown.Items = {'CA', 'GOCA', 'SOCA'};
            app.CFARTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CFARTypeDropDownValueChanged, true);
            app.CFARTypeDropDown.Value = 'GOCA';
            app.ProbabilityofFalseAlarmEditField_2Label = uilabel(app.DCFARParametersPanel);
            app.ProbabilityofFalseAlarmEditField_2Label.Text = 'Probability of False Alarm';
            app.ProbabilityofFalseAlarmEditField_2 = uieditfield(app.DCFARParametersPanel, 'numeric');
            app.ProbabilityofFalseAlarmEditField_2.ValueChangedFcn = createCallbackFcn(app, @ProbabilityofFalseAlarmEditField_2ValueChanged, true);
            app.ProbabilityofFalseAlarmEditField_2.Value = 1e-06;
            app.TrainingBandSizeEditFieldLabel = uilabel(app.DCFARParametersPanel);
            app.TrainingBandSizeEditFieldLabel.Text = 'Training Band Size';
            app.TrainingBandSizeEditField = uieditfield(app.DCFARParametersPanel, 'text');
            app.TrainingBandSizeEditField.ValueChangedFcn = createCallbackFcn(app, @TrainingBandSizeEditFieldValueChanged, true);
            app.TrainingBandSizeEditField.Value = '2 2';
            app.GuardBandSizeEditFieldLabel = uilabel(app.DCFARParametersPanel);
            app.GuardBandSizeEditFieldLabel.Text = 'Guard Band Size';
            app.GuardBandSizeEditField = uieditfield(app.DCFARParametersPanel, 'text');
            app.GuardBandSizeEditField.ValueChangedFcn = createCallbackFcn(app, @GuardBandSizeEditFieldValueChanged, true);
            app.GuardBandSizeEditField.Value = '1 1';
            app.ChannelParametersPanel = uipanel(app.ParametersInitializationTab);
            app.ChannelParametersPanel.Title = 'Channel Parameters';
            app.ClusterDecayFactornsecEditFieldLabel = uilabel(app.ChannelParametersPanel);
            app.ClusterDecayFactornsecEditFieldLabel.Text = 'Cluster Decay Factor (nsec)';
            app.ClusterDecayFactornsecEditField = uieditfield(app.ChannelParametersPanel, 'numeric');
            app.ClusterDecayFactornsecEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterDecayFactornsecEditFieldValueChanged, true);
            app.ClusterDecayFactornsecEditField.Value = 3.12;
            app.RayDecayFactornsecEditFieldLabel = uilabel(app.ChannelParametersPanel);
            app.RayDecayFactornsecEditFieldLabel.Text = 'Ray Decay Factor (nsec)';
            app.RayDecayFactornsecEditField = uieditfield(app.ChannelParametersPanel, 'numeric');
            app.RayDecayFactornsecEditField.ValueChangedFcn = createCallbackFcn(app, @RayDecayFactornsecEditFieldValueChanged, true);
            app.RayDecayFactornsecEditField.Value = 0.91;
            app.ClusterArrivalRate1nsecEditFieldLabel = uilabel(app.ChannelParametersPanel);
            app.ClusterArrivalRate1nsecEditFieldLabel.Text = 'Cluster Arrival Rate (1/nsec)';
            app.ClusterArrivalRate1nsecEditField = uieditfield(app.ChannelParametersPanel, 'numeric');
            app.ClusterArrivalRate1nsecEditField.ValueChangedFcn = createCallbackFcn(app, @ClusterArrivalRate1nsecEditFieldValueChanged, true);
            app.ClusterArrivalRate1nsecEditField.Value = 0.13;
            app.PathLossExponentEditFieldLabel = uilabel(app.ChannelParametersPanel);
            app.PathLossExponentEditFieldLabel.Text = 'Path Loss Exponent';
            app.PathLossExponentEditField = uieditfield(app.ChannelParametersPanel, 'numeric');
            app.PathLossExponentEditField.ValueChangedFcn = createCallbackFcn(app, @PathLossExponentEditFieldValueChanged, true);
            app.PathLossExponentEditField.Value = 2;
            app.RayArrivalRate1nsecEditFieldLabel = uilabel(app.ChannelParametersPanel);
            app.RayArrivalRate1nsecEditFieldLabel.Text = 'Ray Arrival Rate (1/nsec)';
            app.RayArrivalRate1nsecEditField = uieditfield(app.ChannelParametersPanel, 'numeric');
            app.RayArrivalRate1nsecEditField.ValueChangedFcn = createCallbackFcn(app, @RayArrivalRate1nsecEditFieldValueChanged, true);
            app.RayArrivalRate1nsecEditField.Value = 0.37;
            app.NumberofClustersEditFieldLabel = uilabel(app.ChannelParametersPanel);
            app.NumberofClustersEditFieldLabel.Text = 'Number of Clusters';
            app.NumberofClustersEditField = uieditfield(app.ChannelParametersPanel, 'numeric');
            app.NumberofClustersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofClustersEditFieldValueChanged, true);
            app.NumberofClustersEditField.Value = 3;
            app.SystemNoiseTemperatureKEditFieldLabel = uilabel(app.ChannelParametersPanel);
            app.SystemNoiseTemperatureKEditFieldLabel.Text = 'System Noise Temperature (K)';
            app.SystemNoiseTemperatureKEditField = uieditfield(app.ChannelParametersPanel, 'numeric');
            app.SystemNoiseTemperatureKEditField.ValueChangedFcn = createCallbackFcn(app, @SystemNoiseTemperatureKEditFieldValueChanged, true);
            app.SystemNoiseTemperatureKEditField.Value = 296;
            app.NumberofRaysEditFieldLabel = uilabel(app.ChannelParametersPanel);
            app.NumberofRaysEditFieldLabel.Text = 'Number of Rays';
            app.NumberofRaysEditField = uieditfield(app.ChannelParametersPanel, 'numeric');
            app.NumberofRaysEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRaysEditFieldValueChanged, true);
            app.NumberofRaysEditField.Value = 4;
            app.UEParametersPanel = uipanel(app.ParametersInitializationTab);
            app.UEParametersPanel.Title = 'UE Parameters';
            app.TargetStatisticalModelDropDown_2Label = uilabel(app.UEParametersPanel);
            app.TargetStatisticalModelDropDown_2Label.Text = 'Target Statistical Model';
            app.TargetStatisticalModelDropDown_2 = uidropdown(app.UEParametersPanel);
            app.TargetStatisticalModelDropDown_2.Items = {'Nonfluctuating', 'Swerling1', 'Swerling2', 'Swerling3', 'Swerling4'};
            app.TargetStatisticalModelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TargetStatisticalModelDropDown_2ValueChanged, true);
            app.TargetStatisticalModelDropDown_2.Value = 'Swerling1';
            app.MeanRadarCrossSectiondBsmEditFieldLabel = uilabel(app.UEParametersPanel);
            app.MeanRadarCrossSectiondBsmEditFieldLabel.Text = 'Mean Radar Cross Section (dBsm)';
            app.MeanRadarCrossSectiondBsmEditField = uieditfield(app.UEParametersPanel, 'numeric');
            app.MeanRadarCrossSectiondBsmEditField.ValueChangedFcn = createCallbackFcn(app, @MeanRadarCrossSectiondBsmEditFieldValueChanged, true);
            app.MeanRadarCrossSectiondBsmEditField.Value = 20;
            app.MobileRangemLabel = uilabel(app.UEParametersPanel);
            app.MobileRangemLabel.Text = 'Mobile Range (m)';
            app.MobileRangemEditField_2 = uieditfield(app.UEParametersPanel, 'numeric');
            app.MobileRangemEditField_2.ValueChangedFcn = createCallbackFcn(app, @MobileRangemEditField_2ValueChanged, true);
            app.MobileRangemEditField_2.Value = 30;
            app.RxNoiseFiguredBEditField_2Label = uilabel(app.UEParametersPanel);
            app.RxNoiseFiguredBEditField_2Label.Text = 'Rx Noise Figure (dB)';
            app.RxNoiseFiguredBEditField_2 = uieditfield(app.UEParametersPanel, 'numeric');
            app.RxNoiseFiguredBEditField_2.ValueChangedFcn = createCallbackFcn(app, @RxNoiseFiguredBEditField_2ValueChanged, true);
            app.RxNoiseFiguredBEditField_2.Value = 10;
            app.AzimuthandElevationAngleLabel = uilabel(app.UEParametersPanel);
            app.AzimuthandElevationAngleLabel.Text = 'Azimuth Angle (°)';
            app.AzimuthAngleEditField = uieditfield(app.UEParametersPanel, 'text');
            app.AzimuthAngleEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthAngleEditFieldValueChanged, true);
            app.AzimuthAngleEditField.Value = '20';
            app.AbsoluteVelocitykmhLabel = uilabel(app.UEParametersPanel);
            app.AbsoluteVelocitykmhLabel.Text = '2D Absolute Velocity (m/s)';
            app.DAbsoluteVelocitymsEditField = uieditfield(app.UEParametersPanel, 'text');
            app.DAbsoluteVelocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @DAbsoluteVelocitymsEditFieldValueChanged, true);
            app.DAbsoluteVelocitymsEditField.Value = '25 0';
            app.RxGainperRxAntennadBEditField_2Label = uilabel(app.UEParametersPanel);
            app.RxGainperRxAntennadBEditField_2Label.Text = 'Rx Gain per Rx Antenna (dB)';
            app.RxGainperRxAntennadBEditField_2 = uieditfield(app.UEParametersPanel, 'numeric');
            app.RxGainperRxAntennadBEditField_2.ValueChangedFcn = createCallbackFcn(app, @RxGainperRxAntennadBEditField_2ValueChanged, true);
            app.RxGainperRxAntennadBEditField_2.Value = 30;
            app.NumberofRxAntennasDropDown_2Label = uilabel(app.UEParametersPanel);
            app.NumberofRxAntennasDropDown_2Label.Text = 'Number of Rx Antennas';
            app.NumberofRxAntennasDropDown_2 = uidropdown(app.UEParametersPanel);
            app.NumberofRxAntennasDropDown_2.Items = {'2', '4', '8', '16', '32', '64'};
            app.NumberofRxAntennasDropDown_2.ValueChangedFcn = createCallbackFcn(app, @NumberofRxAntennasDropDown_2ValueChanged, true);
            app.NumberofRxAntennasDropDown_2.Value = '4';
            app.ISACSystemParametersPanel = uipanel(app.ParametersInitializationTab);
            app.ISACSystemParametersPanel.Title = 'ISAC System Parameters';
            app.TxGainperTxAntennadBEditFieldLabel = uilabel(app.ISACSystemParametersPanel);
            app.TxGainperTxAntennadBEditFieldLabel.Text = 'Tx Gain per Tx Antenna (dB)';
            app.TxGainperTxAntennadBEditField = uieditfield(app.ISACSystemParametersPanel, 'numeric');
            app.TxGainperTxAntennadBEditField.ValueChangedFcn = createCallbackFcn(app, @TxGainperTxAntennadBEditFieldValueChanged, true);
            app.TxGainperTxAntennadBEditField.Value = 30;
            app.NumberofTxAntennasDropDownLabel = uilabel(app.ISACSystemParametersPanel);
            app.NumberofTxAntennasDropDownLabel.Text = 'Number of Tx Antennas';
            app.NumberofTxAntennasDropDown = uidropdown(app.ISACSystemParametersPanel);
            app.NumberofTxAntennasDropDown.Items = {'2', '4', '8', '16', '32', '64'};
            app.NumberofTxAntennasDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofTxAntennasDropDownValueChanged, true);
            app.NumberofTxAntennasDropDown.Value = '4';
            app.RxGainperRxAntennadBEditFieldLabel = uilabel(app.ISACSystemParametersPanel);
            app.RxGainperRxAntennadBEditFieldLabel.Text = 'Rx Gain per Rx Antenna (dB)';
            app.RxGainperRxAntennadBEditField = uieditfield(app.ISACSystemParametersPanel, 'numeric');
            app.RxGainperRxAntennadBEditField.ValueChangedFcn = createCallbackFcn(app, @RxGainperRxAntennadBEditFieldValueChanged, true);
            app.RxGainperRxAntennadBEditField.Value = 30;
            app.RxNoiseFiguredBEditFieldLabel = uilabel(app.ISACSystemParametersPanel);
            app.RxNoiseFiguredBEditFieldLabel.Text = 'Rx Noise Figure (dB)';
            app.RxNoiseFiguredBEditField = uieditfield(app.ISACSystemParametersPanel, 'numeric');
            app.RxNoiseFiguredBEditField.ValueChangedFcn = createCallbackFcn(app, @RxNoiseFiguredBEditFieldValueChanged, true);
            app.RxNoiseFiguredBEditField.Value = 10;
            app.TxpowerdBmLabel = uilabel(app.ISACSystemParametersPanel);
            app.TxpowerdBmLabel.Text = 'Tx Power per Tx Antenna (dBm)';
            app.TxPowerperTxAntennadBmEditField = uieditfield(app.ISACSystemParametersPanel, 'numeric');
            app.TxPowerperTxAntennadBmEditField.ValueChangedFcn = createCallbackFcn(app, @TxPowerperTxAntennadBmEditFieldValueChanged, true);
            app.NumberofRxAntennasDropDownLabel = uilabel(app.ISACSystemParametersPanel);
            app.NumberofRxAntennasDropDownLabel.Text = 'Number of Rx Antennas';
            app.NumberofRxAntennasDropDown = uidropdown(app.ISACSystemParametersPanel);
            app.NumberofRxAntennasDropDown.Items = {'2', '4', '8', '16', '32', '64'};
            app.NumberofRxAntennasDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofRxAntennasDropDownValueChanged, true);
            app.NumberofRxAntennasDropDown.Value = '16';
            app.DAbsoluteVelocitymsEditField_2Label = uilabel(app.ISACSystemParametersPanel);
            app.DAbsoluteVelocitymsEditField_2Label.Text = '2D Absolute Velocity (m/s)';
            app.DAbsoluteVelocitymsEditField_2 = uieditfield(app.ISACSystemParametersPanel, 'text');
            app.DAbsoluteVelocitymsEditField_2.ValueChangedFcn = createCallbackFcn(app, @DAbsoluteVelocitymsEditField_2ValueChanged, true);
            app.DAbsoluteVelocitymsEditField_2.Value = '10 0';
            app.ArrayModelDropDownLabel = uilabel(app.ISACSystemParametersPanel);
            app.ArrayModelDropDownLabel.Text = 'Array Model';
            app.ArrayModelDropDown = uidropdown(app.ISACSystemParametersPanel);
            app.ArrayModelDropDown.Items = {'Tx Sparse, Rx Dense'};
            app.ArrayModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ArrayModelDropDownValueChanged, true);
            app.ArrayModelDropDown.Value = 'Tx Sparse, Rx Dense';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BUPTLOGO.png');
            app.SensingAidedTerahertzMIMOISACSystemSimulatorLabel = uilabel(app.UIFigure);
            app.SensingAidedTerahertzMIMOISACSystemSimulatorLabel.Text = 'Terahertz MIMO ISAC System Simulator';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'SLNSTLOGO.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = THz_MIMO_ISAC_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end